classdef create_workspace_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WorkspacePathEditFieldLabel  matlab.ui.control.Label
        WorkspacePathEditField       matlab.ui.control.EditField
        languageListBoxLabel         matlab.ui.control.Label
        languageListBox              matlab.ui.control.ListBox
        TrackerNameLabel             matlab.ui.control.Label
        TrackerNameEditField         matlab.ui.control.EditField
        createButton                 matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dir)
            app.WorkspacePathEditField.Value = dir;
        end

        % Button pushed function: createButton
        function createButtonPushed(app, event)
            language = 0;
            switch app.languageListBox.Value
                case 'Matlab'
                    language = 1;
                case 'Python'
                    language = 2;
                case 'C / C++ (native)'
                    language = 3;
                case 'Octave'
                    language = 4;
                case 'Others'
                    language = 5;
            end
            
            try
                cd(app.WorkspacePathEditField.Value);
            catch
                errordlg('Workspace path does not exist.');
                return;
            end
            if isempty(app.TrackerNameEditField.Value)
                errordlg('Tracker name is required.')
                return;
            end
            
            gui_workspace_create(app.TrackerNameEditField.Value,language);
            delete(app);
        end

        % Callback function
        function closeButtonPushed(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WorkspacePathEditFieldLabel = uilabel(app.UIFigure);
            app.WorkspacePathEditFieldLabel.Text = 'Workspace Path';
            app.WorkspacePathEditField = uieditfield(app.UIFigure, 'text');
            app.languageListBoxLabel = uilabel(app.UIFigure);
            app.languageListBoxLabel.Text = 'language';
            app.languageListBox = uilistbox(app.UIFigure);
            app.languageListBox.Items = {'Matlab', 'Python', 'C / C++ (native)', 'Octave', 'Others'};
            app.languageListBox.Value = 'Matlab';
            app.TrackerNameLabel = uilabel(app.UIFigure);
            app.TrackerNameLabel.Text = 'Tracker Name (*)';
            app.TrackerNameEditField = uieditfield(app.UIFigure, 'text');
            app.createButton = uibutton(app.UIFigure, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @createButtonPushed, true);
            app.createButton.Text = 'create';
        end
    end

    methods (Access = public)

        % Construct app
        function app = create_workspace_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end