classdef mainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        testButton               matlab.ui.control.Button
        expButton                matlab.ui.control.Button
        nextFrameButton          matlab.ui.control.Button
        configButton             matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        pervFrameButton          matlab.ui.control.Button
        debugButton              matlab.ui.control.Button
        workspaceButton          matlab.ui.control.Button
        trakerNameLabel          matlab.ui.control.Label
        groupSelectListBoxLabel  matlab.ui.control.Label
        groupSelectListBox       matlab.ui.control.ListBox
        seqSelectListBoxLabel    matlab.ui.control.Label
        seqSelectListBox         matlab.ui.control.ListBox
        EditFieldLabel           matlab.ui.control.Label
        status                   matlab.ui.control.EditField
        EditField2Label          matlab.ui.control.Label
        analyse                  matlab.ui.control.EditField
        moniter                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        tracker_result = []
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            toolkit_path;
            paths = toolkit_path;
            toolkit_dir = paths{1};
            
            seq_path = fullfile(get_global_variable('toolkit_dir', toolkit_dir), 'sequences');
            set_global_variable('check_updates', false);
            set_global_variable('toolkit_dir', toolkit_dir);
            set_global_variable('sequences_base_path', seq_path);
          
            stacks = get_folders(fullfile(toolkit_dir, 'sequences'));
            
            app.groupSelectListBox.Items = stacks;
            set_global_variable('stacklist', stacks);
            
        end

        % Button pushed function: debugButton
        function debugButtonPushed(app, event)
%             imshow('./testPic.png','parent',app.moniter);
            app.status.Value = 'asfdasdf';
        end

        % Button pushed function: workspaceButton
        function workspaceButtonPushed(app, event)
            workspace_dir = uigetdir;
            set_global_variable('workspace_dir', workspace_dir);
            
            configuration_file = fullfile(workspace_dir, 'configuration.m');
            
            if ~exist(configuration_file, 'file')
                create = questdlg('Directory is not a valid VOT workspace. Do you want to create one?',...
                    'Yes');
                switch create
                    case 'Yes'
                        cd(workspace_dir);
                        create_workspace_app(workspace_dir);
                    otherwise
                        return;
                end
            end
            addpath(workspace_dir);
            configuration;
            app.trakerNameLabel.Text = get_global_variable('identifier', 'error');
        end

        % Value changed function: groupSelectListBox
        function groupSelectListBoxValueChanged(app, event)
            stack_name = app.groupSelectListBox.Value;
            seq_path = get_global_variable('sequences_base_path', '');
            files = dir(fullfile(seq_path, stack_name));
            foundseqs = {};
            for i = 1:length(files)
                file = files(i);
                if file.isdir == 1 && file.name(1) ~= '.' && file.name(1) ~= '#'
                    foundseqs = [foundseqs file.name];
                end
            end
            
            foundseqs = get_folders(fullfile(seq_path, stack_name));
            app.seqSelectListBox.Items = foundseqs;
            
            set_global_variable('stack', stack_name);
            set_global_variable('sequences_path',... 
                fullfile(get_global_variable('toolkit_dir', pwd()),...
                'sequences', stack_name));
            
            seq_name = app.seqSelectListBox.Value;
            set_global_variable('seq_name', seq_name);
            set_global_variable('mode', 1);
            set_global_variable('seq_names', {seq_name});
            
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            ws_dir = get_global_variable('workspace_dir', false);
            if islogical(ws_dir)
                errordlg('Workspace has not selected.')
                return;
            end
            try
                cd (ws_dir);
            catch
                errordlg('Workspace dir not exist');
                return;
            end
            
            set_global_variable('stack', app.groupSelectListBox.Value);
            set_global_variable('sequences_path',... 
                fullfile(get_global_variable('toolkit_dir', ''),...
                'sequences', app.groupSelectListBox.Value));
            
            [sequences, ~] = gui_test_init();
            tracker = tracker_load(get_global_variable('identifier', 'error'));
            gui_test(tracker, sequences, app);
        end

        % Value changed function: seqSelectListBox
        function seqSelectListBoxValueChanged(app, event)
            seq_name = app.seqSelectListBox.Value;
            set_global_variable('seq_name', seq_name);
            set_global_variable('mode', 1);
            set_global_variable('seq_names', {seq_name});
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.testButton = uibutton(app.UIFigure, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.expButton = uibutton(app.UIFigure, 'push');
            app.expButton.Text = 'exp';
            app.nextFrameButton = uibutton(app.UIFigure, 'push');
            app.nextFrameButton.Text = 'nextFrame';
            app.configButton = uibutton(app.UIFigure, 'push');
            app.configButton.Text = 'config';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.Text = 'reset';
            app.pervFrameButton = uibutton(app.UIFigure, 'push');
            app.pervFrameButton.Text = 'pervFrame';
            app.debugButton = uibutton(app.UIFigure, 'push');
            app.debugButton.ButtonPushedFcn = createCallbackFcn(app, @debugButtonPushed, true);
            app.debugButton.Text = 'debug';
            app.workspaceButton = uibutton(app.UIFigure, 'push');
            app.workspaceButton.ButtonPushedFcn = createCallbackFcn(app, @workspaceButtonPushed, true);
            app.workspaceButton.Text = 'workspace';
            app.trakerNameLabel = uilabel(app.UIFigure);
            app.trakerNameLabel.Text = 'Not Loaded';
            app.groupSelectListBoxLabel = uilabel(app.UIFigure);
            app.groupSelectListBoxLabel.Text = 'groupSelect';
            app.groupSelectListBox = uilistbox(app.UIFigure);
            app.groupSelectListBox.Items = {};
            app.groupSelectListBox.ValueChangedFcn = createCallbackFcn(app, @groupSelectListBoxValueChanged, true);
            app.groupSelectListBox.Value = {};
            app.seqSelectListBoxLabel = uilabel(app.UIFigure);
            app.seqSelectListBoxLabel.Text = 'seqSelect';
            app.seqSelectListBox = uilistbox(app.UIFigure);
            app.seqSelectListBox.Items = {};
            app.seqSelectListBox.ValueChangedFcn = createCallbackFcn(app, @seqSelectListBoxValueChanged, true);
            app.seqSelectListBox.Value = {};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.status = uieditfield(app.UIFigure, 'text');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.analyse = uieditfield(app.UIFigure, 'text');
            app.moniter = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainForm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end